classdef blackjack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        NumberofPlayersLabel   matlab.ui.control.Label
        Button                 matlab.ui.control.StateButton
        Button_2               matlab.ui.control.StateButton
        BLACKJACKLabel         matlab.ui.control.Label
        QuitButton             matlab.ui.control.Button
        GameModeLabel          matlab.ui.control.Label
        TutorialButton         matlab.ui.control.StateButton
        NormalButton           matlab.ui.control.StateButton
        BetEditFieldLabel      matlab.ui.control.Label
        BetEditField           matlab.ui.control.EditField
        ResetButton            matlab.ui.control.Button
        Button_3               matlab.ui.control.Button
        Button_4               matlab.ui.control.Button
        Button_5               matlab.ui.control.Button
        Button_6               matlab.ui.control.Button
        DoneButton             matlab.ui.control.Button
        BalanceEditFieldLabel  matlab.ui.control.Label
        BalanceEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        numPlayers % Number of players decided on which button is pushed
        gameMode
        betAmount
        quitGame
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            value = app.Button_2.Value;
            app.numPlayers = 2;
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            app.numPlayers = 1;
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            app.quitGame = true;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.BetEditField.Value = '0';
        end

        % Value changed function: BetEditField
        function BetEditFieldValueChanged(app, event)
            value = app.BetEditField.Value;
            
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            app.BetEditField.Value = app.BetEditField.Value + 500;
            app.BalanceEditField.Value = app.BalanceEditField.Value - 500;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.BetEditField.Value = app.BetEditField.Value + 100;
            app.BalanceEditField.Value = app.BalanceEditField.Value - 100;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.BetEditField.Value = app.BetEditField.Value + 50;
            app.BalanceEditField.Value = app.BalanceEditField.Value - 50;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.BetEditField.Value = app.BetEditField.Value + 10;
            app.BalanceEditField.Value = app.BalanceEditField.Value - 10;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.NumberofPlayersLabel = uilabel(app.UIFigure);
            app.NumberofPlayersLabel.Text = 'Number of Players';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '2';
            app.BLACKJACKLabel = uilabel(app.UIFigure);
            app.BLACKJACKLabel.Text = 'BLACKJACK';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit?';
            app.GameModeLabel = uilabel(app.UIFigure);
            app.GameModeLabel.Text = 'Game Mode';
            app.TutorialButton = uibutton(app.UIFigure, 'state');
            app.TutorialButton.Text = 'Tutorial';
            app.NormalButton = uibutton(app.UIFigure, 'state');
            app.NormalButton.Text = 'Normal';
            app.BetEditFieldLabel = uilabel(app.UIFigure);
            app.BetEditFieldLabel.Text = 'Bet';
            app.BetEditField = uieditfield(app.UIFigure, 'text');
            app.BetEditField.ValueChangedFcn = createCallbackFcn(app, @BetEditFieldValueChanged, true);
            app.BetEditField.Value = '0';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.Text = 'Done';
            app.BalanceEditFieldLabel = uilabel(app.UIFigure);
            app.BalanceEditFieldLabel.Text = 'Balance';
            app.BalanceEditField = uieditfield(app.UIFigure, 'text');
            app.BalanceEditField.Value = '2000';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Icon = 'casinoChip.png';
            app.Button_3.Text = '500';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'casinoChip.png';
            app.Button_4.Text = '100';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'casinoChip.png';
            app.Button_5.Text = '50';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'casinoChip.png';
            app.Button_6.Text = '10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blackjack

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end