classdef lab7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        BalanceEditFieldLabel  matlab.ui.control.Label
        BalanceEditField       matlab.ui.control.NumericEditField
        AmountEditFieldLabel   matlab.ui.control.Label
        AmountEditField        matlab.ui.control.NumericEditField
        WithdrawButton         matlab.ui.control.Button
        DepositButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BalanceEditField.Value = 5000;
        end

        % Button pushed function: WithdrawButton
        function WithdrawButtonPushed(app, event)
            app.BalanceEditField.Value = app.BalanceEditField.Value - app.AmountEditField.Value;
        end

        % Button pushed function: DepositButton
        function DepositButtonPushed(app, event)
            app.BalanceEditField.Value = app.BalanceEditField.Value + app.AmountEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BalanceEditFieldLabel = uilabel(app.UIFigure);
            app.BalanceEditFieldLabel.Text = 'Balance';
            app.BalanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmountEditFieldLabel = uilabel(app.UIFigure);
            app.AmountEditFieldLabel.Text = 'Amount';
            app.AmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.WithdrawButton = uibutton(app.UIFigure, 'push');
            app.WithdrawButton.ButtonPushedFcn = createCallbackFcn(app, @WithdrawButtonPushed, true);
            app.WithdrawButton.Text = 'Withdraw';
            app.DepositButton = uibutton(app.UIFigure, 'push');
            app.DepositButton.ButtonPushedFcn = createCallbackFcn(app, @DepositButtonPushed, true);
            app.DepositButton.Text = 'Deposit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end