classdef mainGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        QuitButton              matlab.ui.control.Button
        Bet1EditFieldLabel      matlab.ui.control.Label
        Bet1EditField           matlab.ui.control.NumericEditField
        Balance1EditFieldLabel  matlab.ui.control.Label
        Balance1EditField       matlab.ui.control.NumericEditField
        Bet2EditFieldLabel      matlab.ui.control.Label
        Bet2EditField           matlab.ui.control.NumericEditField
        Balance2EditFieldLabel  matlab.ui.control.Label
        Balance2EditField       matlab.ui.control.NumericEditField
        Image                   matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Image3                  matlab.ui.control.Image
        Image4                  matlab.ui.control.Image
        Image5                  matlab.ui.control.Image
        Image6                  matlab.ui.control.Image
        Image_2                 matlab.ui.control.Image
        Image2_2                matlab.ui.control.Image
        Image3_2                matlab.ui.control.Image
        Image4_2                matlab.ui.control.Image
        Image5_2                matlab.ui.control.Image
        Image6_2                matlab.ui.control.Image
        Image_3                 matlab.ui.control.Image
        Image2_3                matlab.ui.control.Image
        Image3_3                matlab.ui.control.Image
        Image4_3                matlab.ui.control.Image
        Image5_3                matlab.ui.control.Image
        Image6_3                matlab.ui.control.Image
        HitButton               matlab.ui.control.Button
        StandButton             matlab.ui.control.Button
        HitButton_2             matlab.ui.control.Button
        StandButton_2           matlab.ui.control.Button
        Hand1Label              matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        Hand2Label              matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        ErrorLabel              matlab.ui.control.Label
        DealerHandLabel         matlab.ui.control.Label
        Label_3                 matlab.ui.control.Label
        BustLabel               matlab.ui.control.Label
        BustLabel_2             matlab.ui.control.Label
        BlackjackLabel          matlab.ui.control.Label
        BlackjackLabel_2        matlab.ui.control.Label
        PlayAgainButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        gameWindow % to open new startscreen2 after play again button is pressed
        CallingApp % Description
        img
        imgFileNames % filenames of card photos
        numPlayers 
%        player1Hand = 2 % number of cards in hand
%        player2Hand
        dealerHand
        cardValues
        cards 
        shuffledCards %randomized 52x3 array with card index, value, and image file name
        numCardsDealt = 0 %implement counter to select row from array ShuffledCards
        
        player1hits = 0 %number of times player 1 presses "hit"
        player2hits = 0
        %signals whos turn: if 1 then it's their turn
        player1turn = 1
        player2turn = 0
        dealerturn = 0
        
        bet1
        balance1
        bet2
        balance2
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, startApp, numPlayers, bet1, balance1, bet2, balance2)
            app.CallingApp = startApp;
            app.numPlayers = numPlayers;
            app.Bet1EditField.Value = bet1;
            app.Balance1EditField.Value = balance1;
            app.Bet2EditField.Value = bet2;
            app.Balance2EditField.Value = balance2;
            app.bet1 = bet1;
            app.balance1 = balance1;
            app.bet2 = bet2;
            app.balance2 = balance2;
            
            getCards(app)
            dealFirstHand(app)
            
            % Hide Bust & Blackjack text
            app.BustLabel.Visible = 'off';
            app.BustLabel_2.Visible = 'off';
            
            app.BlackjackLabel.Visible = 'off';
            app.BlackjackLabel_2.Visible = 'off';
            
            % Hide Play Again Button
            app.PlayAgainButton.Visible = 'off';

            % Show only the inital hand
            app.Image3.Visible = 'off';
            app.Image4.Visible = 'off';
            app.Image5.Visible = 'off';
            app.Image6.Visible = 'off';
            
            app.Image3_3.Visible = 'off';
            app.Image4_3.Visible = 'off';
            app.Image5_3.Visible = 'off';
            app.Image6_3.Visible = 'off';
            
            app.Image3_2.Visible = 'off';
            app.Image4_2.Visible = 'off';
            app.Image5_2.Visible = 'off';
            app.Image6_2.Visible = 'off';
            
            app.ErrorLabel.Visible = 'off';
            
            
            if(numPlayers == 1)
                app.Bet2EditField.Visible = 'off';
                app.Bet2EditFieldLabel.Visible = 'off';
                app.Balance2EditField.Visible = 'off';
                app.Balance2EditFieldLabel.Visible = 'off';
                app.Hand2Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                
                app.HitButton_2.Visible = 'off';
                app.StandButton_2.Visible = 'off';
                
                app.Image_2.Visible = 'off';
                app.Image2_2.Visible = 'off';
            end
            
            % Disable player 2's buttons to visually tell it's player 1's
            % turn
            if(app.player1turn == 1)
                app.HitButton_2.Enable = 'off';
                app.StandButton_2.Enable = 'off';
            end
        end

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            switch app.player1turn
                case 1
                    hit(app);
                    switch app.player1hits
                        case 0
                            app.Image3.Visible = 'on'; %card image turns on
                            app.Image3.ImageSource = app.shuffledCards{app.numCardsDealt,3}; %adds image for 3rd card
                        case 1
                            app.Image4.Visible = 'on'; %card image turns on
                            app.Image4.ImageSource = app.shuffledCards{app.numCardsDealt,3}; %adds image for 4th card
                        case 2
                            app.Image5.Visible = 'on'; %card image turns on
                            app.Image5.ImageSource = app.shuffledCards{app.numCardsDealt,3}; %adds image for 5th card
                        case 3
                            app.Image6.Visible = 'on'; %card image turns on
                            app.Image6.ImageSource = app.shuffledCards{app.numCardsDealt,3}; %adds image for 6th card
                        otherwise
                            app.Image6.Visible = 'on'; %card image turns on
                            app.Image6.ImageSource = app.shuffledCards{app.numCardsDealt,3}; %updates image 6 slot for over 6 cards in rare cases
                    end
                    app.player1hits = app.player1hits + 1;
                case 0
                    if (app.numPlayers == 2)
                        app.player2turn = 1;
                    elseif (app.numPlayers == 1)
                        app.dealerturn=1;
                        dealerPlay(app);
                    end
                otherwise
                    app.ErrorLabel.Visible = 'on';
            end
            
            
            % Check's player's hands for blackjack or bust after every turn
            if(str2double(app.Label.Text) == 21)
                app.BlackjackLabel.Visible = 'on';
                for z=(1:4)
                    pause(0.25)
                    app.BlackjackLabel.FontColor = [1 0 0];
                    pause(0.25)
                    app.BlackjackLabel.FontColor = [1 1 1];
                end
                bustaudio(app);
                app.BlackjackLabel.FontSize = 20;
                app.BlackjackLabel.FontColor = [1 0 0];
                app.HitButton.Enable = 'off';
                app.StandButton.Enable = 'off';
                app.player2turn = 1;
                % If player 1 turns ends by blackjack then player 2 can
                % play
                app.HitButton_2.Enable = 'on';
                app.StandButton_2.Enable = 'on';
            elseif(str2double(app.Label.Text) > 21)
                app.BustLabel.Visible = 'on';
                for z=(1:4)
                    pause(0.25)
                    app.BustLabel.FontColor = [1 0 0];
                    pause(0.25)
                    app.BustLabel.FontColor = [1 1 1];
                end
                wahwah(app)
                updateBalanceTest(app);
                %app.BustLabel.FontColor=  [1 0 0];
                app.HitButton.Enable = 'off';
                app.StandButton.Enable = 'off';
                if(app.numPlayers == 1)
                    app.PlayAgainButton.Visible = 'on';
                end
                app.player2turn = 1;
            end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: HitButton_2
        function HitButton_2Pushed(app, event)
            switch app.player2turn
                case 1
                    hit(app);
                    switch app.player2hits
                        case 0
                            app.Image3_2.Visible = 'on'; %card image turns on
                            app.Image3_2.ImageSource = app.shuffledCards{app.numCardsDealt,3}; %adds image for 3rd card
                        case 1
                            app.Image4_2.Visible = 'on'; %card image turns on
                            app.Image4_2.ImageSource = app.shuffledCards{app.numCardsDealt,3}; %adds image for 4th card
                        case 2
                            app.Image5_2.Visible = 'on'; %card image turns on
                            app.Image5_2.ImageSource = app.shuffledCards{app.numCardsDealt,3}; %adds image for 5th card
                        case 3
                            app.Image6_2.Visible = 'on'; %card image turns on
                            app.Image6_2.ImageSource = app.shuffledCards{app.numCardsDealt,3}; %adds image for 6th card
                        otherwise
                            app.Image6_2.Visible = 'on'; %card image turns on
                            app.Image6_2.ImageSource = app.shuffledCards{app.numCardsDealt,3}; %updates image 6 slot for over 6 cards in rare cases
                    end
                    app.player2hits = app.player2hits + 1;
                case 0
                    app.dealerturn = 1;
                    dealerPlay(app);
                    app.Bet2EditField.Value = 420;
            end
            
            if(str2double(app.Label_2.Text) > 21)
                app.BustLabel_2.Visible = 'on';
                app.HitButton_2.Enable = 'off';
            end
            
            if(str2double(app.Label_2.Text) == 21)
                app.BlackjackLabel_2.Visible = 'on';
                for z=(1:4)
                    pause(0.25)
                    app.BlackjackLabel_2.FontColor = [1 0 0];
                    pause(0.25)
                    app.BlackjackLabel_2.FontColor = [1 1 1];
                end
                app.BlackjackLabel.FontSize = 20;
                app.BlackjackLabel_2.FontColor = [1 0 0];
                app.HitButton_2.Enable = 'off';
                app.StandButton_2.Enable = 'off';
                bustaudio(app);
            elseif(str2double(app.Label_2.Text) > 21)
                app.BustLabel_2.Visible = 'on';
                for z=(1:4)
                    pause(0.25)
                    app.BustLabel_2.FontColor = [1 0 0];
                    pause(0.25)
                    app.BustLabel_2.FontColor = [1 1 1];
                end
                app.BustLabel_2.FontColor=  [1 0 0];
                app.HitButton_2.Enable = 'off';
                app.StandButton_2.Enable = 'off';
                wahwah(app);
            end
            
        end

        % Button pushed function: StandButton
        function StandButtonPushed(app, event)
             app.player1turn = 0;
             
             app.StandButton.Enable = 'off';
             
             % Enable player 2's buttons once player 1 ends turn by
             % standing
             app.HitButton_2.Enable = 'on';
             app.StandButton_2.Enable = 'on';
             
            if app.numPlayers == 1
                app.dealerturn = 1;
                dealerPlay(app);
            elseif app.numPlayers == 2 
                app.player2turn = 1;
            end
        end

        % Button pushed function: StandButton_2
        function StandButton_2Pushed(app, event)
            app.StandButton_2.Enable = 'off';
            
            app.player2turn = 0;
            app.dealerturn = 1;
            dealerPlay(app);
            testBalance(app);
        end

        % Button pushed function: PlayAgainButton
        function PlayAgainButtonPushed(app, event)
            app.gameWindow = startScreen2(app, app.numPlayers, app.Balance1EditField.Value, app.Balance2EditField.Value);
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.Bet1EditFieldLabel = uilabel(app.UIFigure);
            app.Bet1EditFieldLabel.Text = 'Bet 1';
            app.Bet1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance1EditFieldLabel = uilabel(app.UIFigure);
            app.Balance1EditFieldLabel.Text = 'Balance 1';
            app.Balance1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance1EditField.Value = 2000;
            app.Bet2EditFieldLabel = uilabel(app.UIFigure);
            app.Bet2EditFieldLabel.Text = 'Bet 2';
            app.Bet2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance2EditFieldLabel = uilabel(app.UIFigure);
            app.Balance2EditFieldLabel.Text = 'Balance 2';
            app.Balance2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance2EditField.Value = 2000;
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.Image5 = uiimage(app.UIFigure);
            app.Image6 = uiimage(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image3_2 = uiimage(app.UIFigure);
            app.Image4_2 = uiimage(app.UIFigure);
            app.Image5_2 = uiimage(app.UIFigure);
            app.Image6_2 = uiimage(app.UIFigure);
            app.Image_3 = uiimage(app.UIFigure);
            app.Image2_3 = uiimage(app.UIFigure);
            app.Image3_3 = uiimage(app.UIFigure);
            app.Image4_3 = uiimage(app.UIFigure);
            app.Image5_3 = uiimage(app.UIFigure);
            app.Image6_3 = uiimage(app.UIFigure);
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Text = 'Hit';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushed, true);
            app.StandButton.Text = 'Stand';
            app.HitButton_2 = uibutton(app.UIFigure, 'push');
            app.HitButton_2.ButtonPushedFcn = createCallbackFcn(app, @HitButton_2Pushed, true);
            app.HitButton_2.Text = 'Hit';
            app.StandButton_2 = uibutton(app.UIFigure, 'push');
            app.StandButton_2.ButtonPushedFcn = createCallbackFcn(app, @StandButton_2Pushed, true);
            app.StandButton_2.Text = 'Stand';
            app.Hand1Label = uilabel(app.UIFigure);
            app.Hand1Label.Text = 'Hand 1';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.Hand2Label = uilabel(app.UIFigure);
            app.Hand2Label.Text = 'Hand 2';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '0';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = 'Error!!!';
            app.DealerHandLabel = uilabel(app.UIFigure);
            app.DealerHandLabel.Text = 'Dealer Hand';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '0';
            app.BustLabel = uilabel(app.UIFigure);
            app.BustLabel.Text = 'Bust!';
            app.BustLabel_2 = uilabel(app.UIFigure);
            app.BustLabel_2.Text = 'Bust!';
            app.BlackjackLabel = uilabel(app.UIFigure);
            app.BlackjackLabel.Text = 'Blackjack!';
            app.BlackjackLabel_2 = uilabel(app.UIFigure);
            app.BlackjackLabel_2.Text = 'Blackjack!';
            app.PlayAgainButton = uibutton(app.UIFigure, 'push');
            app.PlayAgainButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAgainButtonPushed, true);
            app.PlayAgainButton.Text = 'Play Again?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainGame(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end