classdef startScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Button                   matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        BetAmountEditFieldLabel  matlab.ui.control.Label
        BetAmountEditField       matlab.ui.control.NumericEditField
        ResetButton              matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        NumberofPlayersLabel     matlab.ui.control.Label
        QuitButton               matlab.ui.control.Button
        Bet1EditFieldLabel       matlab.ui.control.Label
        Bet1EditField            matlab.ui.control.NumericEditField
        Balance1EditFieldLabel   matlab.ui.control.Label
        Balance1EditField        matlab.ui.control.NumericEditField
        Bet2EditFieldLabel       matlab.ui.control.Label
        Bet2EditField            matlab.ui.control.NumericEditField
        Balance2EditFieldLabel   matlab.ui.control.Label
        Balance2EditField        matlab.ui.control.NumericEditField
        DoneButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        gameWindow %open mainGame window
        instructWindow
        numPlayers = 0; %number of players; 1 = 1-player, 2 = 2-players
        bet1 = 0;
        balance1 = 2000;
        bet2 = 0;
        balance2 = 2000;
        numDonePushed = 0; %number of time done button is pushed
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %betting and balance fields not visible until user selects
            %number of players
            app.Bet1EditField.Visible = 'off';
            app.Bet1EditFieldLabel.Visible = 'off';
            app.Bet2EditField.Visible = 'off';
            app.Bet2EditFieldLabel.Visible = 'off';
            app.Balance1EditField.Visible = 'off';
            app.Balance1EditFieldLabel.Visible = 'off';
            app.Balance2EditField.Visible = 'off';
            app.Balance2EditFieldLabel.Visible = 'off';
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            %ONE-PLAYER PLAY
            app.numPlayers = 1;
            % If only one player, then player 2 has no bet
            app.bet2 = 0;
            app.balance2 = 2000;
            
            app.Bet1EditField.Visible = 'on';
            app.Bet1EditFieldLabel.Visible = 'on';
            app.Balance1EditField.Visible = 'on';
            app.Balance1EditFieldLabel.Visible = 'on';
            
            if(app.numPlayers == 2)
            app.Bet2EditField.Visible = 'on';
            app.Bet2EditFieldLabel.Visible = 'on';
            app.Balance2EditField.Visible = 'on';
            app.Balance2EditFieldLabel.Visible = 'on';
            elseif(app.numPlayers == 1)
            app.Bet2EditField.Visible = 'off';
            app.Bet2EditFieldLabel.Visible = 'off';
            app.Balance2EditField.Visible = 'off';
            app.Balance2EditFieldLabel.Visible = 'off';
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            %TWO-PLAYER PLAY
            app.numPlayers = 2;
            app.Bet1EditField.Visible = 'on';
            app.Bet1EditFieldLabel.Visible = 'on';
            app.Balance1EditField.Visible = 'on';
            app.Balance1EditFieldLabel.Visible = 'on';
            
            app.Bet2EditField.Visible = 'on';
            app.Bet2EditFieldLabel.Visible = 'on';
            app.Balance2EditField.Visible = 'on';
            app.Balance2EditFieldLabel.Visible = 'on';
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            %Done pushed opens mainGame.mlapp depending on if conditions:
            %ie. number of players and numDonePushed counter
            app.numDonePushed = app.numDonePushed + 1;
            if(app.numPlayers == 1 && app.numDonePushed == 1)
                app.gameWindow = mainGame(app, app.numPlayers, app.bet1, app.balance1, app.bet2, app.balance2);
                app.instructWindow = instructions(app);
                closereq;
            elseif(app.numPlayers == 2 && app.numDonePushed == 1)
                app.BetAmountEditField.Value = 0;
            elseif(app.numPlayers == 2 && app.numDonePushed == 2)
                app.gameWindow = mainGame(app, app.numPlayers, app.bet1, app.balance1, app.bet2, app.balance2);
                app.instructWindow = instructions(app);
                closereq;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %500 button functionality
            if(app.balance1 >= 500 && app.numDonePushed == 0)
                app.bet1 = app.bet1 + 500;
                app.balance1 = app.balance1 - 500;
                app.Bet1EditField.Value = app.bet1;
                app.Balance1EditField.Value = app.balance1;
                app.BetAmountEditField.Value = app.bet1;
            elseif(app.balance2 >= 500 && app.numDonePushed == 1)
                app.bet2 = app.bet2 + 500;
                app.balance2 = app.balance2 - 500;
                app.Bet2EditField.Value = app.bet2;
                app.Balance2EditField.Value = app.balance2;
                app.BetAmountEditField.Value = app.bet2;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %100 button functionality 
            if(app.balance1 >= 100 && app.numDonePushed == 0)
                app.bet1 = app.bet1 + 100;
                app.balance1 = app.balance1 - 100;
                app.Bet1EditField.Value = app.bet1;
                app.Balance1EditField.Value = app.balance1;
                app.BetAmountEditField.Value = app.bet1;
            elseif(app.balance2 >= 100 && app.numDonePushed == 1)
                app.bet2 = app.bet2 + 100;
                app.balance2 = app.balance2 - 100;
                app.Bet2EditField.Value = app.bet2;
                app.Balance2EditField.Value = app.balance2;
                app.BetAmountEditField.Value = app.bet2;
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %50 button functionality  
            if(app.balance1 >= 50 && app.numDonePushed == 0)
                app.bet1 = app.bet1 + 50;
                app.balance1 = app.balance1 - 50;
                app.Bet1EditField.Value = app.bet1;
                app.Balance1EditField.Value = app.balance1;
                app.BetAmountEditField.Value = app.bet1;
            elseif(app.balance2 >= 50 && app.numDonePushed == 1)
                app.bet2 = app.bet2 + 50;
                app.balance2 = app.balance2 - 50;
                app.Bet2EditField.Value = app.bet2;
                app.Balance2EditField.Value = app.balance2;
                app.BetAmountEditField.Value = app.bet2;
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %10 button functionality   
            if(app.balance1 >= 10 && app.numDonePushed == 0)
                app.bet1 = app.bet1 + 10;
                app.balance1 = app.balance1 - 10;
                app.Bet1EditField.Value = app.bet1;
                app.Balance1EditField.Value = app.balance1;
                app.BetAmountEditField.Value = app.bet1;
            elseif(app.balance2 >= 10 && app.numDonePushed == 1)
                app.bet2 = app.bet2 + 10;
                app.balance2 = app.balance2 - 10;
                app.Bet2EditField.Value = app.bet2;
                app.Balance2EditField.Value = app.balance2;
                app.BetAmountEditField.Value = app.bet2;
            end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %reset button functionality 
            switch app.numDonePushed 
                 case 0
                    app.bet1 = 0;
                    app.balance1 = 2000;
                    app.Bet1EditField.Value = app.bet1;
                    app.Balance1EditField.Value = app.balance1;
                    app.BetAmountEditField.Value = app.bet1;
                 case 1
                    app.bet2 = 0;
                    app.balance2 = 2000;
                    app.Bet2EditField.Value = app.bet2;
                    app.Balance2EditField.Value = app.balance2;
                    app.BetAmountEditField.Value = app.bet2;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'casinoChip.png';
            app.Button.Text = '500';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'casinoChip.png';
            app.Button_2.Text = '100';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'casinoChip.png';
            app.Button_3.Text = '50';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'casinoChip.png';
            app.Button_4.Text = '10';
            app.BetAmountEditFieldLabel = uilabel(app.UIFigure);
            app.BetAmountEditFieldLabel.Text = 'Bet Amount';
            app.BetAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Tooltip = {'If you have one player, Click "1", then click the amount you wish to bet. Then click "Done"'};
            app.Button_5.Text = '1';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Tooltip = {'If you have 2 players, click "2". Player 1 should click the amount they wish to bet then click "Done". Player 2 should do the same.'};
            app.Button_6.Text = '2';
            app.NumberofPlayersLabel = uilabel(app.UIFigure);
            app.NumberofPlayersLabel.Text = 'Number of Players';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.Bet1EditFieldLabel = uilabel(app.UIFigure);
            app.Bet1EditFieldLabel.Text = 'Bet 1';
            app.Bet1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance1EditFieldLabel = uilabel(app.UIFigure);
            app.Balance1EditFieldLabel.Text = 'Balance 1';
            app.Balance1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance1EditField.Value = 2000;
            app.Bet2EditFieldLabel = uilabel(app.UIFigure);
            app.Bet2EditFieldLabel.Text = 'Bet 2';
            app.Bet2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance2EditFieldLabel = uilabel(app.UIFigure);
            app.Balance2EditFieldLabel.Text = 'Balance 2';
            app.Balance2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Balance2EditField.Value = 2000;
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = startScreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end